classdef genetic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GeneticAlgorithmVisualizationLabel  matlab.ui.control.Label
        ImportNNButton                  matlab.ui.control.Button
        CurrNNselectedTextAreaLabel     matlab.ui.control.Label
        CurrNNselectedTextArea          matlab.ui.control.TextArea
        HyperparametersforthegeneticalgorithmLabel  matlab.ui.control.Label
        CrossoverFractionSliderLabel    matlab.ui.control.Label
        CrossoverFractionSlider         matlab.ui.control.Slider
        EliteCountEditFieldLabel        matlab.ui.control.Label
        EliteCountEditField             matlab.ui.control.NumericEditField
        PopulationSizeEditFieldLabel    matlab.ui.control.Label
        PopulationSizeEditField         matlab.ui.control.NumericEditField
        InProgressLabel                 matlab.ui.control.Label
        ProgressLamp                    matlab.ui.control.Lamp
        StartTrainingButton             matlab.ui.control.Button
        MaxStallGenerationsEditFieldLabel  matlab.ui.control.Label
        MaxStallGenerationsEditField    matlab.ui.control.NumericEditField
        MaxTrainingTimesEditFieldLabel  matlab.ui.control.Label
        MaxTrainingTimesEditField       matlab.ui.control.NumericEditField
        AlbedoDropDownLabel             matlab.ui.control.Label
        AlbedoDropDown                  matlab.ui.control.DropDown
        ParallelPlotsButton             matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        ReturntoMainButton              matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cf % Description
        elite
        pop
        
        neural_net = 'radbas_38_4.7e-4.mat'; 
    end
    
    methods (Access = public)
        
    end
    
    methods (Access = private)
        function f = objectiveFcn(app, x,albedo)
            %% Load NN model
            % load NN_tansig_2.3e-4.mat
            % load NN_logsig_2.6e-4.mat
            % load NN_tansig_76_HN.mat
        
            load(app.neural_net)
        
            %% Calculate efficiency from NN
            x = x*1e9;
            Eff = -net(x');
        
            f= Eff(albedo,1);
        
        
            %% Writing parametric search space to file
            Collated_data = [x(1) x(2) x(3) x(4) x(5) x(6) -f];
        %     save(strcat(int2str(albedo), '.mat'), 'Collated_data')
            
            fileID = fopen(strcat(int2str(albedo), '.txt'),'a');
            fprintf(fileID,'%e %e %e %e %e %e %f\n',Collated_data);
            fclose(fileID);
        
        end
        
        function f = classify_here(app, data, objective, albedo)
            % filename is the albedo.mat file
            % load filename data
            max = objective(albedo); % max efficiency
            high = max - 0.01; % threshold for high
            medium = high - 0.015; % threshold for medium
            % the rest is low
            
            h = size(data);
            num = h(1);
            
            fileID = fopen(strcat(int2str(albedo), 'Cat', '.txt'),'a');
            
            for i = 1: num - 1
                row = data(i, :);
                effi = row(7);
                if effi == round(max, 6)
                    fprintf(fileID, 'Optimal\n');
                elseif effi >= high
                    fprintf(fileID, 'High\n');
                elseif (medium <= effi) && (effi < high)
                    fprintf(fileID, 'Med\n');
                else
                    fprintf(fileID, 'Low\n');
                    
                end  
                
            end
            
            fprintf(fileID, 'Base\n');
            
            fclose(fileID);
            
            txt = importdata(strcat(int2str(albedo), 'Cat', '.txt'));
            save(strcat(int2str(albedo), 'Cat'), 'txt');
    
       end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportNNButton
        function ImportNNButtonPushed(app, event)
            [file, path] = uigetfile;
            filename = [path file];
            app.neural_net = filename;
            app.CurrNNselectedTextArea.Value = file; % specifies the NN chosen
        end

        % Button pushed function: StartTrainingButton
        function StartTrainingButtonPushed(app, event)
            app.ProgressLamp.Color = [0.9294 0.6902 0.1294];
            app.InProgressLabel.Text = "In Progress";
            
            load(app.neural_net);
            for i = 1:8
                delete(strcat(int2str(i), '.mat'));
                delete(strcat(int2str(i), '.txt'));
            end
            
            % boundaries for 6 thicknesses in nm
            lb = [300;400;100;100;20;400].*1e-9;
            ub = [500;1000;350;350;90;1000].*1e-9;
            num_var = 6;
            % delete 'Parameter_w_eta.txt';
            for i = 1:8
                delete(strcat(int2str(i), '.mat'));
                delete(strcat(int2str(i), '.txt'));
            end
            
            % Set nondefault solver options
            options = optimoptions('ga',...
                'CrossoverFraction',app.CrossoverFractionSlider.Value,...
                'EliteCount',app.EliteCountEditField.Value,...
                'PopulationSize',app.PopulationSizeEditField.Value,...
                'UseParallel', false,... % turn this on if your computer have parallel computing tool
                'MaxStallGenerations', app.MaxStallGenerationsEditField.Value,... % default value = 50
                'MaxTime', app.MaxTrainingTimesEditField.Value); % default value = 100 * number of vars (6);
            
            solution = zeros(8,6);
            objectiveValue = zeros(8,1);
            
            % Solve using GA
            % loop through all 8 albedo conditions 
            for albedo = 1:8
                [solution(albedo,:),objectiveValue(albedo,1)] = ga({@app.objectiveFcn ,albedo},num_var,[],[],[],[],lb,ub,[],[],...
                options);
                
                base_dim = ([370, 500, 150, 170, 80, 620] * 1e9)';
                base_eff = net(base_dim);
                final_line = [370 500 150 170 80 620 base_eff(albedo)];
                
                data = importdata(strcat(int2str(albedo), '.txt')); % load raw data from txt
                data = unique(data, 'rows'); % get rid of duplicate rows
                data = sortrows(data, 7); % sort the data in ascending efficiencies 
                data(end+1, :) = final_line; % append the base case to data
                save(int2str(albedo), 'data');
                
            end
            % Clear variables
            clearvars options
            eff = - objectiveValue;
            save('sol_eff.mat', 'solution', 'eff')
            beep
            
            % plot 1. optimal eff across 8 albedos
            x = [1 2 3 4 5 6 7 8]'; % 8 albedo conditions
            plot(app.UIAxes, x, eff * 100, "LineStyle","-.", "Marker","+")
            
            % plot 2. 6 optimal geometric parameters for specified albedo
            parameters = [1 2 3 4 5 6]'; % 6 geometric parameters
            switch app.AlbedoDropDown.Value
                case '1. 0.2 AM1.5G'
                    plot(app.UIAxes2, parameters, solution(1, :)' * 1E9, "LineStyle","-.", "Marker","*")
                    
                case '2. Concrete'
                    plot(app.UIAxes2, parameters, solution(2, :)' * 1E9, "LineStyle","-.", "Marker","*")
                    
                case '3. Dry grass'
                    plot(app.UIAxes2, parameters, solution(3, :)' * 1E9, "LineStyle","-.", "Marker","*")
                    
                case '4. Green grass'
                    plot(app.UIAxes2, parameters, solution(4, :)' * 1E9, "LineStyle","-.", "Marker","*")
                    
                case '5. Red brick' 
                    plot(app.UIAxes2, parameters, solution(5, :)' * 1E9, "LineStyle","-.", "Marker","*")
                    
                case '6. Roof shingle'
                    plot(app.UIAxes2, parameters, solution(6, :)' * 1E9, "LineStyle","-.", "Marker","*")
                    
                case '7. Snow'
                    plot(app.UIAxes2, parameters, solution(7, :)' * 1E9, "LineStyle","-.", "Marker","*")
                    
                case '8. White sand'
                    plot(app.UIAxes2, parameters, solution(8, :)' * 1E9, "LineStyle","-.", "Marker","*")
                
            end
            
            
            app.ProgressLamp.Color = [0.00,1.00,0.00];
            app.InProgressLabel.Text = 'Done';
            
        end

        % Button pushed function: ParallelPlotsButton
        function ParallelPlotsButtonPushed(app, event)
            close all

            for i = 1:8
                delete(strcat(int2str(i), 'Cat', '.mat'));
                delete(strcat(int2str(i), 'Cat', '.txt'));
            end
            
            load('sol_eff.mat');
            
            dict = containers.Map({'1. 0.2 AM1.5G', '2. Concrete', '3. Dry grass', '4. Green grass', '5. Red brick', '6. Roof shingle', '7. Snow', '8. White sand'}, ... 
            {1, 2, 3, 4, 5, 6, 7, 8});
            
            albedo = dict(app.AlbedoDropDown.Value); 
            
            load(strcat(int2str(albedo), '.mat'));
        
            f = @app.classify_here;
            f(data, eff, albedo);
        
            load(strcat(int2str(albedo), 'Cat', '.mat'))
        
            p = parallelplot(data);
            ax = gca; % current axes 
            ax.CoordinateTickLabels = 'tex';
            Ticklabels = ["L1 / nm","L3 / nm","L6 / nm","L8 / nm","L9 / nm",...
                                "L10 / nm","Eff_tot"];
        
            p.CoordinateTickLabels = Ticklabels;
            p.GroupData = txt;
            Green = [153/255,255/255,153/255];
            Blue = [153/255,204/255,255/255];
            Red = [255/255,153/255,153/255];
            Black = [0,0,0];
            Yellow = [255/255,255/255,102/255];
        
            p.Color = [Green;Blue;Red;Black;Black]; % low, medium, high, optimal, base
            p.LineWidth = [0.5,0.5,0.5,3,3];
            p.LineStyle = {'-';'-';'-';'-';':'};
            p.FontName = 'Times New Roman';
            p.FontSize = 20;
            
            a = 'Albedo condition';
            b = ' ';
            c = app.AlbedoDropDown.Value;
            results = [a, b, c];
            
            title(results)
            figname = strcat('albedo', int2str(albedo), ' pllplot.fig');
            savefig(figname)
            
        end

        % Callback function: CrossoverFractionSlider, 
        % CrossoverFractionSlider
        function CrossoverFractionSliderValueChanged(app, event)
            value = app.CrossoverFractionSlider.Value;
            app.EditField.Value = string(round(value, 2));
        end

        % Value changed function: AlbedoDropDown
        function AlbedoDropDownValueChanged(app, event)
            value = app.AlbedoDropDown.Value;
            parameters = [1 2 3 4 5 6]'; % 6 geometric parameters
            load('sol_eff.mat');
            dict = containers.Map({'1. 0.2 AM1.5G', '2. Concrete', '3. Dry grass', '4. Green grass', '5. Red brick', '6. Roof shingle', '7. Snow', '8. White sand'}, ... 
            {1, 2, 3, 4, 5, 6, 7, 8});
            
            albedo = dict(app.AlbedoDropDown.Value);
            plot(app.UIAxes2, parameters, solution(albedo, :)' * 1E9, "LineStyle","-.", "Marker","*");
        end

        % Button pushed function: ReturntoMainButton
        function ReturntoMainButtonPushed(app, event)
            delete(app) 
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.ProgressLamp.Color = [0.9294 0.6902 0.1294];
            app.InProgressLabel.Text = "In Progress";
            
            app.CrossoverFractionSlider.Value = 0;
            app.EliteCountEditField.Value = 0;
            app.PopulationSizeEditField.Value = 0;
            app.MaxStallGenerationsEditField.Value = 0;
            app.MaxTrainingTimesEditField.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GeneticAlgorithmVisualizationLabel = uilabel(app.UIFigure);
            app.GeneticAlgorithmVisualizationLabel.Text = 'Genetic Algorithm & Visualization ';
            app.ImportNNButton = uibutton(app.UIFigure, 'push');
            app.ImportNNButton.ButtonPushedFcn = createCallbackFcn(app, @ImportNNButtonPushed, true);
            app.ImportNNButton.Icon = 'import_icon.png';
            app.ImportNNButton.Text = 'Import NN';
            app.CurrNNselectedTextAreaLabel = uilabel(app.UIFigure);
            app.CurrNNselectedTextAreaLabel.Text = 'Curr NN selected';
            app.CurrNNselectedTextArea = uitextarea(app.UIFigure);
            app.CurrNNselectedTextArea.Editable = 'off';
            app.CurrNNselectedTextArea.Value = {'radbas_38_4.7e-4.mat'};
            app.HyperparametersforthegeneticalgorithmLabel = uilabel(app.UIFigure);
            app.HyperparametersforthegeneticalgorithmLabel.Text = 'Hyperparameters for the genetic algorithm';
            app.CrossoverFractionSliderLabel = uilabel(app.UIFigure);
            app.CrossoverFractionSliderLabel.Text = 'Crossover Fraction';
            app.CrossoverFractionSlider = uislider(app.UIFigure);
            app.CrossoverFractionSlider.Limits = [0 1];
            app.CrossoverFractionSlider.ValueChangedFcn = createCallbackFcn(app, @CrossoverFractionSliderValueChanged, true);
            app.CrossoverFractionSlider.ValueChangingFcn = createCallbackFcn(app, @CrossoverFractionSliderValueChanged, true);
            app.EliteCountEditFieldLabel = uilabel(app.UIFigure);
            app.EliteCountEditFieldLabel.Text = 'Elite Count';
            app.EliteCountEditField = uieditfield(app.UIFigure, 'numeric');
            app.EliteCountEditField.Tooltip = {'Elite count should not exceed population size'};
            app.PopulationSizeEditFieldLabel = uilabel(app.UIFigure);
            app.PopulationSizeEditFieldLabel.Text = 'Population Size';
            app.PopulationSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.InProgressLabel = uilabel(app.UIFigure);
            app.InProgressLabel.Text = 'In Progress';
            app.ProgressLamp = uilamp(app.UIFigure);
            app.StartTrainingButton = uibutton(app.UIFigure, 'push');
            app.StartTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrainingButtonPushed, true);
            app.StartTrainingButton.Icon = 'start-icon-png-19.jpg';
            app.StartTrainingButton.Text = 'Start Training';
            app.MaxStallGenerationsEditFieldLabel = uilabel(app.UIFigure);
            app.MaxStallGenerationsEditFieldLabel.Text = 'Max Stall Generations';
            app.MaxStallGenerationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxTrainingTimesEditFieldLabel = uilabel(app.UIFigure);
            app.MaxTrainingTimesEditFieldLabel.Text = 'Max Training Time [s]';
            app.MaxTrainingTimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.AlbedoDropDownLabel = uilabel(app.UIFigure);
            app.AlbedoDropDownLabel.Text = 'Albedo';
            app.AlbedoDropDown = uidropdown(app.UIFigure);
            app.AlbedoDropDown.Items = {'1. 0.2 AM1.5G', '2. Concrete', '3. Dry grass', '4. Green grass', '5. Red brick', '6. Roof shingle', '7. Snow', '8. White sand'};
            app.AlbedoDropDown.ValueChangedFcn = createCallbackFcn(app, @AlbedoDropDownValueChanged, true);
            app.AlbedoDropDown.Value = '1. 0.2 AM1.5G';
            app.ParallelPlotsButton = uibutton(app.UIFigure, 'push');
            app.ParallelPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @ParallelPlotsButtonPushed, true);
            app.ParallelPlotsButton.Text = 'Parallel Plots';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.ReturntoMainButton = uibutton(app.UIFigure, 'push');
            app.ReturntoMainButton.ButtonPushedFcn = createCallbackFcn(app, @ReturntoMainButtonPushed, true);
            app.ReturntoMainButton.Icon = 'return_icon.png';
            app.ReturntoMainButton.Text = 'Return to Main';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Icon = 'reset_icon.jpg';
            app.ResetButton.Text = 'Reset';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Optimal Efficiency Across Different Albedos')
            xlabel(app.UIAxes, 'Albedo')
            ylabel(app.UIAxes, 'Efficiency % ')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [1 8];
            app.UIAxes.YLim = [22 42];
            app.UIAxes.XTick = [0 1 2 3 4 5 6 7 8];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Optimal Geometric Dimensions')
            xlabel(app.UIAxes2, 'Geometric Parameters ')
            ylabel(app.UIAxes2, 'Thickness in nm')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [1 6];
            app.UIAxes2.YLim = [0 1000];
            app.UIAxes2.XTick = [1 2 3 4 5 6];
            app.UIAxes2.XTickLabel = {'l^F_T'; 'l^P_T'; 'l^B_T'; 'l^F_B'; 'l^H_B'; 'l^P_B'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = genetic

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end