classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        l_FTSliderLabel              matlab.ui.control.Label
        l_FTSlider                   matlab.ui.control.Slider
        l_PTSliderLabel              matlab.ui.control.Label
        l_PTSlider                   matlab.ui.control.Slider
        l_BTSliderLabel              matlab.ui.control.Label
        l_BTSlider                   matlab.ui.control.Slider
        l_FBSliderLabel              matlab.ui.control.Label
        l_FBSlider                   matlab.ui.control.Slider
        l_HBSliderLabel              matlab.ui.control.Label
        l_HBSlider                   matlab.ui.control.Slider
        l_PBSliderLabel              matlab.ui.control.Label
        l_PBSlider                   matlab.ui.control.Slider
        StartButton                  matlab.ui.control.Button
        EfficiencyLabel              matlab.ui.control.Label
        EfficiencyGauge              matlab.ui.control.SemicircularGauge
        AlbedoDropDownLabel          matlab.ui.control.Label
        AlbedoDropDown               matlab.ui.control.DropDown
        ImportNNButton               matlab.ui.control.Button
        CurrNNselectedTextAreaLabel  matlab.ui.control.Label
        CurrNNselectedTextArea       matlab.ui.control.TextArea
        TrainYourOwnNNButton         matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        TextArea                     matlab.ui.control.TextArea
        TextArea_2                   matlab.ui.control.TextArea
        TextArea_3                   matlab.ui.control.TextArea
        TextArea_4                   matlab.ui.control.TextArea
        TextArea_5                   matlab.ui.control.TextArea
        TextArea_6                   matlab.ui.control.TextArea
        TextArea_7                   matlab.ui.control.TextArea
        OptimizeEfficiencyButton     matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        neural_net = 'radbas_38_4.7e-4.mat';  % the neural network loaded by the user, default is radbas_38
        DialogApp % Dialog box app 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function UIAxesButtonDown(app, event)
       
        end

        % Callback function
        function l_PBSliderValueChanging(app, event)
  
        end

        % Callback function
        function l_HBSliderValueChanged(app, event)
          
        end

        % Callback function: AlbedoDropDown, StartButton, l_BTSlider, 
        % l_BTSlider, l_FBSlider, l_FBSlider, l_FTSlider, 
        % l_FTSlider, l_HBSlider, l_HBSlider, l_PBSlider, 
        % l_PBSlider, l_PTSlider, l_PTSlider
        function StartButtonPushed(app, event)
            l_FT = app.l_FTSlider.Value;
            l_PT = app.l_PTSlider.Value;
            l_BT = app.l_BTSlider.Value;
            l_FB = app.l_FBSlider.Value;
            l_HB = app.l_HBSlider.Value;
            l_PB = app.l_PBSlider.Value;
            
            app.TextArea_2.Value = string(round(app.l_FTSlider.Value)); % update the slider in the text box
            app.TextArea_3.Value = string(round(app.l_PTSlider.Value));
            app.TextArea_4.Value = string(round(app.l_BTSlider.Value));
            app.TextArea_5.Value = string(round(app.l_FBSlider.Value));
            app.TextArea_6.Value = string(round(app.l_HBSlider.Value));
            app.TextArea_7.Value = string(round(app.l_PBSlider.Value));
            
            load(app.neural_net);
            dim = ([l_FT, l_PT, l_BT, l_FB, l_HB, l_PB])'; % unit conversion!
            
            results = net(dim);
            x = [1 2 3 4 5 6 7 8]';
            plot(app.UIAxes, x, results * 100, "LineStyle","-.", "Marker","+")
            
            dict = containers.Map({'1. 0.2 AM1.5G', '2. Concrete', '3. Dry grass', '4. Green grass', '5. Red brick', '6. Roof shingle', '7. Snow', '8. White sand'}, ... 
            {1, 2, 3, 4, 5, 6, 7, 8});
            
            albedo = dict(app.AlbedoDropDown.Value); 
            app.EfficiencyGauge.Value = results(albedo)* 100;
            
            a = round(results(albedo) * 100, 2);
            app.TextArea.Value = strcat(string(a), '%');
        end

        % Callback function
        function l_PBSliderValueChanged(app, event)
           
        end

        % Button pushed function: ImportNNButton
        function ImportNNButtonPushed(app, event)
            [file, path, index] = uigetfile;
            filename = [path file];
            app.neural_net = filename;
            app.CurrNNselectedTextArea.Value = file; % specifies the NN chosen
            
        end

        % Button pushed function: TrainYourOwnNNButton
        function TrainYourOwnNNButtonPushed(app, event)
            app.DialogApp = train();
        end

        % Callback function
        function TextArea_2ValueChanged(app, event)
            value = app.TextArea_2.Value;
            
            app.l_FTSlider.Value = str2double(app.TextArea_2.Value); % update the slider value 
        end

        % Button pushed function: OptimizeEfficiencyButton
        function OptimizeEfficiencyButtonPushed(app, event)
            app.DialogApp = genetic();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.l_FTSliderLabel = uilabel(app.UIFigure);
            app.l_FTSliderLabel.Tooltip = {'Front contact electrode top cell thickness [nm]'};
            app.l_FTSliderLabel.Text = 'l_FT';
            app.l_FTSlider = uislider(app.UIFigure);
            app.l_FTSlider.Limits = [300 500];
            app.l_FTSlider.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.l_FTSlider.ValueChangingFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.l_FTSlider.Tag = 'l_FT';
            app.l_FTSlider.Value = 300;
            app.l_PTSliderLabel = uilabel(app.UIFigure);
            app.l_PTSliderLabel.Tooltip = {'Perovskite layer of the top cell thickness [nm]'};
            app.l_PTSliderLabel.Text = 'l_PT';
            app.l_PTSlider = uislider(app.UIFigure);
            app.l_PTSlider.Limits = [400 1000];
            app.l_PTSlider.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.l_PTSlider.ValueChangingFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.l_PTSlider.Tooltip = {''};
            app.l_PTSlider.Value = 400;
            app.l_BTSliderLabel = uilabel(app.UIFigure);
            app.l_BTSliderLabel.Tooltip = {'Bottom contact electrode layer of the top cell thickness [nm]'};
            app.l_BTSliderLabel.Text = 'l_BT';
            app.l_BTSlider = uislider(app.UIFigure);
            app.l_BTSlider.Limits = [100 350];
            app.l_BTSlider.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.l_BTSlider.ValueChangingFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.l_BTSlider.Value = 100;
            app.l_FBSliderLabel = uilabel(app.UIFigure);
            app.l_FBSliderLabel.Tooltip = {'Front contact electrode layer of the bottom cell thickness [nm]'};
            app.l_FBSliderLabel.Text = 'l_FB';
            app.l_FBSlider = uislider(app.UIFigure);
            app.l_FBSlider.Limits = [100 350];
            app.l_FBSlider.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.l_FBSlider.ValueChangingFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.l_FBSlider.Value = 100;
            app.l_HBSliderLabel = uilabel(app.UIFigure);
            app.l_HBSliderLabel.Tooltip = {'Hole transport layer of the bottom cell thickness [nm]'};
            app.l_HBSliderLabel.Text = 'l_HB';
            app.l_HBSlider = uislider(app.UIFigure);
            app.l_HBSlider.Limits = [20 90];
            app.l_HBSlider.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.l_HBSlider.ValueChangingFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.l_HBSlider.Value = 20;
            app.l_PBSliderLabel = uilabel(app.UIFigure);
            app.l_PBSliderLabel.Tooltip = {'Perovskite layer of the bottom cell thickness [nm]'};
            app.l_PBSliderLabel.Text = 'l_PB';
            app.l_PBSlider = uislider(app.UIFigure);
            app.l_PBSlider.Limits = [400 1000];
            app.l_PBSlider.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.l_PBSlider.ValueChangingFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.l_PBSlider.Value = 400;
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Icon = 'start-icon-png-19.jpg';
            app.StartButton.Visible = 'off';
            app.StartButton.Text = 'Start';
            app.EfficiencyLabel = uilabel(app.UIFigure);
            app.EfficiencyLabel.Text = 'Efficiency [%]';
            app.EfficiencyGauge = uigauge(app.UIFigure, 'semicircular');
            app.EfficiencyGauge.Limits = [22 42];
            app.AlbedoDropDownLabel = uilabel(app.UIFigure);
            app.AlbedoDropDownLabel.Text = 'Albedo';
            app.AlbedoDropDown = uidropdown(app.UIFigure);
            app.AlbedoDropDown.Items = {'1. 0.2 AM1.5G', '2. Concrete', '3. Dry grass', '4. Green grass', '5. Red brick', '6. Roof shingle', '7. Snow', '8. White sand'};
            app.AlbedoDropDown.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.AlbedoDropDown.Value = '1. 0.2 AM1.5G';
            app.ImportNNButton = uibutton(app.UIFigure, 'push');
            app.ImportNNButton.ButtonPushedFcn = createCallbackFcn(app, @ImportNNButtonPushed, true);
            app.ImportNNButton.Icon = 'import_icon.png';
            app.ImportNNButton.Text = 'Import NN';
            app.CurrNNselectedTextAreaLabel = uilabel(app.UIFigure);
            app.CurrNNselectedTextAreaLabel.Text = 'Curr NN selected';
            app.CurrNNselectedTextArea = uitextarea(app.UIFigure);
            app.CurrNNselectedTextArea.Editable = 'off';
            app.CurrNNselectedTextArea.Value = {'radbas_38_4.7e-4.mat'};
            app.TrainYourOwnNNButton = uibutton(app.UIFigure, 'push');
            app.TrainYourOwnNNButton.ButtonPushedFcn = createCallbackFcn(app, @TrainYourOwnNNButtonPushed, true);
            app.TrainYourOwnNNButton.Text = 'Train Your Own NN';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Layer image.png';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_4 = uitextarea(app.UIFigure);
            app.TextArea_5 = uitextarea(app.UIFigure);
            app.TextArea_6 = uitextarea(app.UIFigure);
            app.TextArea_7 = uitextarea(app.UIFigure);
            app.OptimizeEfficiencyButton = uibutton(app.UIFigure, 'push');
            app.OptimizeEfficiencyButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeEfficiencyButtonPushed, true);
            app.OptimizeEfficiencyButton.Text = 'Optimize Efficiency';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Efficiencies of Different Albedo Conditions')
            xlabel(app.UIAxes, 'Albedo')
            ylabel(app.UIAxes, 'Efficiency [%]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [1 8];
            app.UIAxes.YLim = [22 42];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end