classdef train < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LoadTrainingDataInputLabel      matlab.ui.control.Label
        LoadTrainingDataInputDropDown   matlab.ui.control.DropDown
        LoadTrainingDataOutputLabel     matlab.ui.control.Label
        LoadTrainingDataOutputDropDown  matlab.ui.control.DropDown
        LoadTestDataButton              matlab.ui.control.Button
        StartTrainingButton             matlab.ui.control.Button
        TestErrorTextAreaLabel          matlab.ui.control.Label
        TestErrorTextArea               matlab.ui.control.TextArea
        NumberofhiddenlayersDropDownLabel  matlab.ui.control.Label
        NumberofhiddenlayersDropDown    matlab.ui.control.DropDown
        Layer1SizeEditFieldLabel        matlab.ui.control.Label
        Layer1SizeEditField             matlab.ui.control.NumericEditField
        Layer2SizeEditFieldLabel        matlab.ui.control.Label
        Layer2SizeEditField             matlab.ui.control.NumericEditField
        Layer1ActivationFcnDropDownLabel  matlab.ui.control.Label
        Layer1ActivationFcnDropDown     matlab.ui.control.DropDown
        Layer2ActivationFcnDropDownLabel  matlab.ui.control.Label
        Layer2ActivationFcnDropDown     matlab.ui.control.DropDown
        ReturntoMainButton              matlab.ui.control.Button
        FastLoadDataFolderButton        matlab.ui.control.Button
        TrainYourOwnNeuralNetworkLabel  matlab.ui.control.Label
        InProgressLabel                 matlab.ui.control.Label
        ProgressLamp                    matlab.ui.control.Lamp
        Step1LoadthetrainingandtestdataYoucanfastloadLabel  matlab.ui.control.Label
        Layer3SizeEditFieldLabel        matlab.ui.control.Label
        Layer3SizeEditField             matlab.ui.control.NumericEditField
        Layer3ActivationFcnDropDownLabel  matlab.ui.control.Label
        Layer3ActivationFcnDropDown     matlab.ui.control.DropDown
        STEP2SelecthyperparameterstotrainyourNNLabel  matlab.ui.control.Label
        STEP3TrainandsaveyourNNLabel    matlab.ui.control.Label
        NeuralnetworksavedtothecurrentdirectoryLabel  matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        STEP4TotrainanotherNNclickResetLabel  matlab.ui.control.Label
    end

    properties (Access = private)
        % test set
        X_test
        Y_test
        
        % training set input
        dat1
        dat2
        dat3
        dat4
        dat5
        dat6
    
        % training set output
        dat7
        dat8
        dat9
        dat10
        dat11
        dat12
        dat13
        dat14
        
        Inputs
        Targets
        mode = 2 % load mode: 1 -> fast mode, 2-> individual files
    end 

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadTestDataButton
        function LoadTestDataButtonPushed(app, event)
            [file, path] = uigetfile;
            filename = [path file];

            test_IO = load(filename);      
            app.X_test = test_IO(:,1:6)';
            app.Y_test = test_IO(:,7:14)';
            
        end

        % Value changed function: LoadTrainingDataOutputDropDown
        function LoadTrainingDataOutputDropDownValueChanged(app, event)
            value = app.LoadTrainingDataOutputDropDown.Value;
            [file, path] = uigetfile;
            filename = [path file];
            app.mode = 2;
            
            switch app.LoadTrainingDataOutputDropDown.Value
                case '1. 0.2 Sunlight'
                    app.dat7 = load(filename)';
                    
                case '2. Concrete'
                    app.dat8 = load(filename)';
                    
                case '3. Dry grass'
                    app.dat9 = load(filename)';
                    
                case '4. Green grass'
                    app.dat10 = load(filename)';
                    
                case '5. Red brick' 
                    app.dat11 = load(filename)';
                    
                case '6. Roof shingle'
                    app.dat12 = load(filename)';
                    
                case '7. Snow'
                    app.dat13 = load(filename)';
                    
                case '8. White sand'
                    app.dat14 = load(filename)';
                    
                case 'None'
                    % do nothing
                 
            end
        end

        % Value changed function: LoadTrainingDataInputDropDown
        function LoadTrainingDataInputDropDownValueChanged(app, event)
            value = app.LoadTrainingDataInputDropDown.Value;
            app.mode = 2; % means slow load mode 
            [file, path] = uigetfile;
            filename = [path file];
            
            switch app.LoadTrainingDataInputDropDown.Value
                case 'l_FT'  
                    app.dat1 = load(filename)';
                    
                case 'l_PT'
                    app.dat2 = load(filename)';
                    
                case 'l_BT'
                    app.dat3 = load(filename)';
                    
                case 'l_FB'
                    app.dat4 = load(filename)';
                    
                case 'l_HB' 
                    app.dat5 = load(filename)';
                    
                case 'l_PB'
                    app.dat6 = load(filename)';
                
                case 'None'
                    % do nothing
                 
            end
        end

        % Button pushed function: StartTrainingButton
        function StartTrainingButtonPushed(app, event)
            app.InProgressLabel.Visible = 'on';
            app.ProgressLamp.Visible = 'on';
            
            if app.mode == 2 % individual file load mode
                app.Inputs = [app.dat1; app.dat2; app.dat3; app.dat4; app.dat5; app.dat6];
                app.Targets = [app.dat7(3, :); app.dat8(3, :); app.dat9(3, :); ...
                       app.dat10(3, :); app.dat11(3, :); app.dat12(3, :); ...
                       app.dat13(3, :); app.dat14(3, :)];
            end
            
            if app.NumberofhiddenlayersDropDown.Value == '1'
           
                nodes = app.Layer1SizeEditField.Value;
                activeFcn = app.Layer1ActivationFcnDropDown.Value;
                
                trainFcn = 'trainbr';
                lr = 0.01;
                maxEpochs = 1000;
                momentum = 0.85;
                
                net = fitnet(nodes, trainFcn);
                net.divideParam.trainRatio = 75/100; % 75% training data 
                net.divideParam.valRatio = 0/100; % no validation data
                net.divideParam.testRatio = 25/100;
                
                % Activation function for hidden layers
                  net.layers{1}.transferFcn = activeFcn;  % Hidden layer 1
                  % performance function
                  net.performFcn = 'mse';
                  net.performParam.normalization = 'standard';
                  % Train the Network
                  net.trainParam.showWindow = false;
                  net.trainParam.showCommandLine = false;
                  
                  net.trainParam.lr = lr;
                  net.trainParam.mc = momentum;
                  net.trainParam.epochs = maxEpochs;
        %         net.trainParam.regularization = L2;
                   
                  [net,~] = train(net,app.Inputs, app.Targets);
            
                   % Test the Network
                  YPredicted = net(app.X_test);
                  valError = perform(net,app.Y_test,YPredicted); % evaluates how the NN performs
                  % net: neural network
                  % YTrain: Target data
                  % YPredicted: Output data
                  % returns network performance calculated according to the net.performFcn and net.performParam property values
                  fileName = activeFcn + "_" + nodes;
                  save(fileName,'net','valError') % save the object named net and valError
                  cons = [];
                  
                  app.TestErrorTextArea.Value = string(valError);
                  
            elseif app.NumberofhiddenlayersDropDown.Value == '2'
                
                nodes1 = app.Layer1SizeEditField.Value;
                nodes2= app.Layer2SizeEditField.Value;
                layer_sizes = [nodes1 nodes2];
                
                activeFcn1 = app.Layer1ActivationFcnDropDown.Value;
                activeFcn2 = app.Layer2ActivationFcnDropDown.Value;
                
                trainFcn = 'trainbr';
                lr = 0.01;
                maxEpochs = 1000;
                momentum = 0.85;
                
                net = fitnet(layer_sizes, trainFcn);
                net.divideParam.trainRatio = 75/100; % 75% training data 
                net.divideParam.valRatio = 0/100; % no validation data
                net.divideParam.testRatio = 25/100;
                
                % Activation function for hidden layers
                  net.layers{1}.transferFcn = activeFcn1;  % Hidden layer 1
                  net.layers{2}.transferFcn = activeFcn2;  % Hidden layer 1
                  
                  % performance function
                  net.performFcn = 'mse';
                  net.performParam.normalization = 'standard';
                  % Train the Network
                  net.trainParam.showWindow = false;
                  net.trainParam.showCommandLine = false;
                  
                  net.trainParam.lr = lr;
                  net.trainParam.mc = momentum;
                  net.trainParam.epochs = maxEpochs;
        %         net.trainParam.regularization = L2;
                   
        
                  [net,~] = train(net,app.Inputs, app.Targets);
                  
                   % Test the Network
                  YPredicted = net(app.X_test);
                  valError = perform(net,app.Y_test,YPredicted); % evaluates how the NN performs
                  % net: neural network
                  % YTrain: Target data
                  % YPredicted: Output data
                  % returns network performance calculated according to the net.performFcn and net.performParam property values
                  fileName = activeFcn1 + "_" + nodes1 + "_" + activeFcn2 + "_" + nodes2;
                  save(fileName,'net','valError') % save the object named net and valError
                  cons = [];
                  
                  app.TestErrorTextArea.Value = string(valError);
            
            elseif app.NumberofhiddenlayersDropDown.Value == '3'
                
                nodes1 = app.Layer1SizeEditField.Value;
                nodes2= app.Layer2SizeEditField.Value;
                nodes3 = app.Layer3SizeEditField.Value;
                layer_sizes = [nodes1 nodes2 nodes3];
                
                activeFcn1 = app.Layer1ActivationFcnDropDown.Value;
                activeFcn2 = app.Layer2ActivationFcnDropDown.Value;
                activeFcn3 = app.Layer3ActivationFcnDropDown.Value;
                
                trainFcn = 'trainbr';
                lr = 0.01;
                maxEpochs = 1000;
                momentum = 0.85;
                
                net = fitnet(layer_sizes, trainFcn);
                net.divideParam.trainRatio = 75/100; % 75% training data 
                net.divideParam.valRatio = 0/100; % no validation data
                net.divideParam.testRatio = 25/100;
                
                % Activation function for hidden layers
                  net.layers{1}.transferFcn = activeFcn1;  % Hidden layer 1
                  net.layers{2}.transferFcn = activeFcn2;  % Hidden layer 2
                  net.layers{3}.transferFcn = activeFcn3;  % Hidden layer 3
                  
                  % performance function
                  net.performFcn = 'mse';
                  net.performParam.normalization = 'standard';
                  % Train the Network
                  net.trainParam.showWindow = false;
                  net.trainParam.showCommandLine = false;
                  
                  net.trainParam.lr = lr;
                  net.trainParam.mc = momentum;
                  net.trainParam.epochs = maxEpochs;
        %         net.trainParam.regularization = L2;
                   
                  [net,~] = train(net,app.Inputs, app.Targets);
                  
                   % Test the Network
                  YPredicted = net(app.X_test);
                  valError = perform(net,app.Y_test,YPredicted); % evaluates how the NN performs
                  % net: neural network
                  % YTrain: Target data
                  % YPredicted: Output data
                  % returns network performance calculated according to the net.performFcn and net.performParam property values
                  fileName = activeFcn1 + "_" + nodes1 + "_" + activeFcn2 + "_" + nodes2 + activeFcn3 + "_" + nodes3;
                  save(fileName,'net','valError') % save the object named net and valError
                  cons = [];
                  
                  app.TestErrorTextArea.Value = string(valError);
            end
            
            app.ProgressLamp.Color = [0.00,1.00,0.00];
            app.InProgressLabel.Text = 'Done';
            app.NeuralnetworksavedtothecurrentdirectoryLabel.Visible = 'on';
            
        end

        % Value changed function: NumberofhiddenlayersDropDown
        function NumberofhiddenlayersDropDownValueChanged(app, event)
            value = app.NumberofhiddenlayersDropDown.Value;
            if value == '1'
                % make only layer 1 visible 
                app.Layer1ActivationFcnDropDown.Visible = 'on';
                app.Layer1ActivationFcnDropDownLabel.Visible = 'on';
                app.Layer1SizeEditField.Visible = 'on';
                app.Layer1SizeEditFieldLabel.Visible = 'on';
                
                app.Layer2ActivationFcnDropDown.Visible = 'off';
                app.Layer2ActivationFcnDropDownLabel.Visible = 'off';
                app.Layer2SizeEditField.Visible = 'off';
                app.Layer2SizeEditFieldLabel.Visible = 'off';
                
                app.Layer3ActivationFcnDropDown.Visible = 'off';
                app.Layer3ActivationFcnDropDownLabel.Visible = 'off';
                app.Layer3SizeEditField.Visible = 'off';
                app.Layer3SizeEditFieldLabel.Visible = 'off';
                
            elseif value == '2'
                % make layer 1 and layer 2 visible 
                app.Layer1ActivationFcnDropDown.Visible = 'on';
                app.Layer1ActivationFcnDropDownLabel.Visible = 'on';
                app.Layer1SizeEditField.Visible = 'on';
                app.Layer1SizeEditFieldLabel.Visible = 'on';
                
                app.Layer2ActivationFcnDropDown.Visible = 'on';
                app.Layer2ActivationFcnDropDownLabel.Visible = 'on';
                app.Layer2SizeEditField.Visible = 'on';
                app.Layer2SizeEditFieldLabel.Visible = 'on';
                
                app.Layer3ActivationFcnDropDown.Visible = 'off';
                app.Layer3ActivationFcnDropDownLabel.Visible = 'off';
                app.Layer3SizeEditField.Visible = 'off';
                app.Layer3SizeEditFieldLabel.Visible = 'off';
                
            elseif value == '3'
                % make all 3 layers visible 
                app.Layer1ActivationFcnDropDown.Visible = 'on';
                app.Layer1ActivationFcnDropDownLabel.Visible = 'on';
                app.Layer1SizeEditField.Visible = 'on';
                app.Layer1SizeEditFieldLabel.Visible = 'on';
                
                app.Layer2ActivationFcnDropDown.Visible = 'on';
                app.Layer2ActivationFcnDropDownLabel.Visible = 'on';
                app.Layer2SizeEditField.Visible = 'on';
                app.Layer2SizeEditFieldLabel.Visible = 'on';
                
                app.Layer3ActivationFcnDropDown.Visible = 'on';
                app.Layer3ActivationFcnDropDownLabel.Visible = 'on';
                app.Layer3SizeEditField.Visible = 'on';
                app.Layer3SizeEditFieldLabel.Visible = 'on';
                
            elseif value == 'None'
                % make all 3 layers invisible 
                app.Layer1ActivationFcnDropDown.Visible = 'off';
                app.Layer1ActivationFcnDropDownLabel.Visible = 'off';
                app.Layer1SizeEditField.Visible = 'off';
                app.Layer1SizeEditFieldLabel.Visible = 'off';
                
                app.Layer2ActivationFcnDropDown.Visible = 'off';
                app.Layer2ActivationFcnDropDownLabel.Visible = 'off';
                app.Layer2SizeEditField.Visible = 'off';
                app.Layer2SizeEditFieldLabel.Visible = 'off';
                
                app.Layer3ActivationFcnDropDown.Visible = 'off';
                app.Layer3ActivationFcnDropDownLabel.Visible = 'off';
                app.Layer3SizeEditField.Visible = 'off';
                app.Layer3SizeEditFieldLabel.Visible = 'off';
                
            end
        end

        % Button pushed function: ReturntoMainButton
        function ReturntoMainButtonPushed(app, event)
             % Call main app's public function
           % updateplot(app.CallingApp,app.EditField.Value,app.DropDown.Value);
        
           % Delete the dialog box  
           delete(app) 
        end

        % Button pushed function: FastLoadDataFolderButton
        function FastLoadDataFolderButtonPushed(app, event)
            path = uigetdir;
            cd(path) % change directory to the user's selection
            files = dir('*.txt'); % all the .txt files
            n = length(files);
            for i = 1:n
                load(files(i).name)
            end
            
            app.Inputs = [l_FT l_PT l_BT l_FB l_HB l_PB]';
            app.Targets = [Output_02(:, 3) Output_con(:, 3) Output_DG(:, 3) ...
                       Output_GG(:, 3) Output_RB(:, 3) Output_RS(:, 3) ...
                       Output_S(:, 3) Output_WS(:, 3)]';
                   
            app.X_test = test_IO(:,1:6)';
            app.Y_test = test_IO(:,7:14)';
            app.mode = 1; % change mode to fast mode 
            cd ..
                        
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.NumberofhiddenlayersDropDown.Value = 'None';
            app.InProgressLabel.Visible = 'on';
            app.InProgressLabel.Text = 'In Progress';
            app.ProgressLamp.Visible = 'on';
            app.ProgressLamp.Color = [0.93,0.69,0.13];
            
            app.NeuralnetworksavedtothecurrentdirectoryLabel.Visible = 'off';
            app.TestErrorTextArea.Value = 'Click Start Traning to get error';
            
            % make all 3 layers invisible 
            app.Layer1ActivationFcnDropDown.Visible = 'off';
            app.Layer1ActivationFcnDropDownLabel.Visible = 'off';
            app.Layer1SizeEditField.Visible = 'off';
            app.Layer1SizeEditFieldLabel.Visible = 'off';
                
            app.Layer2ActivationFcnDropDown.Visible = 'off';
            app.Layer2ActivationFcnDropDownLabel.Visible = 'off';
            app.Layer2SizeEditField.Visible = 'off';
            app.Layer2SizeEditFieldLabel.Visible = 'off';
                
            app.Layer3ActivationFcnDropDown.Visible = 'off';
            app.Layer3ActivationFcnDropDownLabel.Visible = 'off';
            app.Layer3SizeEditField.Visible = 'off';
            app.Layer3SizeEditFieldLabel.Visible = 'off';
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadTrainingDataInputLabel = uilabel(app.UIFigure);
            app.LoadTrainingDataInputLabel.Text = '2) Load Training Data: Input';
            app.LoadTrainingDataInputDropDown = uidropdown(app.UIFigure);
            app.LoadTrainingDataInputDropDown.Items = {'None', 'l_FT', 'l_PT', 'l_BT', 'l_FB', 'l_HB', 'l_PB'};
            app.LoadTrainingDataInputDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadTrainingDataInputDropDownValueChanged, true);
            app.LoadTrainingDataInputDropDown.Value = 'None';
            app.LoadTrainingDataOutputLabel = uilabel(app.UIFigure);
            app.LoadTrainingDataOutputLabel.Text = '2) Load Training Data: Output ';
            app.LoadTrainingDataOutputDropDown = uidropdown(app.UIFigure);
            app.LoadTrainingDataOutputDropDown.Items = {'None', '1. 0.2 Sunlight', '2. Concrete', '3. Dry grass', '4. Green grass', '5. Red brick', '6. Roof shingle', '7. Snow', '8. White sand'};
            app.LoadTrainingDataOutputDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadTrainingDataOutputDropDownValueChanged, true);
            app.LoadTrainingDataOutputDropDown.Value = 'None';
            app.LoadTestDataButton = uibutton(app.UIFigure, 'push');
            app.LoadTestDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTestDataButtonPushed, true);
            app.LoadTestDataButton.Text = '2) Load Test Data';
            app.StartTrainingButton = uibutton(app.UIFigure, 'push');
            app.StartTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrainingButtonPushed, true);
            app.StartTrainingButton.Icon = 'start-icon-png-19.jpg';
            app.StartTrainingButton.Text = 'Start Training';
            app.TestErrorTextAreaLabel = uilabel(app.UIFigure);
            app.TestErrorTextAreaLabel.Text = 'Test Error';
            app.TestErrorTextArea = uitextarea(app.UIFigure);
            app.TestErrorTextArea.Editable = 'off';
            app.NumberofhiddenlayersDropDownLabel = uilabel(app.UIFigure);
            app.NumberofhiddenlayersDropDownLabel.Text = 'Number of hidden layers';
            app.NumberofhiddenlayersDropDown = uidropdown(app.UIFigure);
            app.NumberofhiddenlayersDropDown.Items = {'None', '1', '2', '3'};
            app.NumberofhiddenlayersDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberofhiddenlayersDropDownValueChanged, true);
            app.NumberofhiddenlayersDropDown.Value = 'None';
            app.Layer1SizeEditFieldLabel = uilabel(app.UIFigure);
            app.Layer1SizeEditFieldLabel.Visible = 'off';
            app.Layer1SizeEditFieldLabel.Text = 'Layer 1 Size';
            app.Layer1SizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.Layer1SizeEditField.Visible = 'off';
            app.Layer2SizeEditFieldLabel = uilabel(app.UIFigure);
            app.Layer2SizeEditFieldLabel.Visible = 'off';
            app.Layer2SizeEditFieldLabel.Text = 'Layer 2 Size';
            app.Layer2SizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.Layer2SizeEditField.Visible = 'off';
            app.Layer1ActivationFcnDropDownLabel = uilabel(app.UIFigure);
            app.Layer1ActivationFcnDropDownLabel.Visible = 'off';
            app.Layer1ActivationFcnDropDownLabel.Text = 'Layer 1 Activation Fcn';
            app.Layer1ActivationFcnDropDown = uidropdown(app.UIFigure);
            app.Layer1ActivationFcnDropDown.Items = {'radbas', 'radbasn', 'tansig', 'logsig'};
            app.Layer1ActivationFcnDropDown.Visible = 'off';
            app.Layer1ActivationFcnDropDown.Value = 'radbas';
            app.Layer2ActivationFcnDropDownLabel = uilabel(app.UIFigure);
            app.Layer2ActivationFcnDropDownLabel.Visible = 'off';
            app.Layer2ActivationFcnDropDownLabel.Text = 'Layer 2 Activation Fcn';
            app.Layer2ActivationFcnDropDown = uidropdown(app.UIFigure);
            app.Layer2ActivationFcnDropDown.Items = {'radbas', 'radbasn', 'tansig', 'logsig'};
            app.Layer2ActivationFcnDropDown.Visible = 'off';
            app.Layer2ActivationFcnDropDown.Value = 'radbas';
            app.ReturntoMainButton = uibutton(app.UIFigure, 'push');
            app.ReturntoMainButton.ButtonPushedFcn = createCallbackFcn(app, @ReturntoMainButtonPushed, true);
            app.ReturntoMainButton.Icon = 'return_icon.png';
            app.ReturntoMainButton.Text = 'Return to Main';
            app.FastLoadDataFolderButton = uibutton(app.UIFigure, 'push');
            app.FastLoadDataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @FastLoadDataFolderButtonPushed, true);
            app.FastLoadDataFolderButton.Text = '1) Fast Load Data Folder';
            app.TrainYourOwnNeuralNetworkLabel = uilabel(app.UIFigure);
            app.TrainYourOwnNeuralNetworkLabel.Text = 'Train Your Own Neural Network';
            app.InProgressLabel = uilabel(app.UIFigure);
            app.InProgressLabel.Text = 'In Progress';
            app.ProgressLamp = uilamp(app.UIFigure);
            app.Step1LoadthetrainingandtestdataYoucanfastloadLabel = uilabel(app.UIFigure);
            app.Step1LoadthetrainingandtestdataYoucanfastloadLabel.Text = {'-> STEP 1. Load the training and test data. '; 'You can 1) fast load the data by selecting '; 'the folder containting your raw datasets'; 'OR 2) select individual text files to load'; ''; ''; ''; ''};
            app.Layer3SizeEditFieldLabel = uilabel(app.UIFigure);
            app.Layer3SizeEditFieldLabel.Visible = 'off';
            app.Layer3SizeEditFieldLabel.Text = 'Layer 3 Size';
            app.Layer3SizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.Layer3SizeEditField.Visible = 'off';
            app.Layer3ActivationFcnDropDownLabel = uilabel(app.UIFigure);
            app.Layer3ActivationFcnDropDownLabel.Visible = 'off';
            app.Layer3ActivationFcnDropDownLabel.Text = 'Layer 3 Activation Fcn';
            app.Layer3ActivationFcnDropDown = uidropdown(app.UIFigure);
            app.Layer3ActivationFcnDropDown.Items = {'radbas', 'radbasn', 'tansig', 'logsig'};
            app.Layer3ActivationFcnDropDown.Visible = 'off';
            app.Layer3ActivationFcnDropDown.Value = 'radbas';
            app.STEP2SelecthyperparameterstotrainyourNNLabel = uilabel(app.UIFigure);
            app.STEP2SelecthyperparameterstotrainyourNNLabel.Text = '-> STEP 2. Select hyperparameters to train your NN';
            app.STEP3TrainandsaveyourNNLabel = uilabel(app.UIFigure);
            app.STEP3TrainandsaveyourNNLabel.Text = '-> STEP 3. Train and save your NN';
            app.NeuralnetworksavedtothecurrentdirectoryLabel = uilabel(app.UIFigure);
            app.NeuralnetworksavedtothecurrentdirectoryLabel.Visible = 'off';
            app.NeuralnetworksavedtothecurrentdirectoryLabel.Text = 'Neural network saved to the current directory ';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Icon = 'reset_icon.jpg';
            app.ResetButton.Text = 'Reset';
            app.STEP4TotrainanotherNNclickResetLabel = uilabel(app.UIFigure);
            app.STEP4TotrainanotherNNclickResetLabel.Text = '-> STEP 4. To train another NN, click Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = train

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end